classdef Ujoint_CN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TrapezoidModeSelectionSwitch  matlab.ui.control.Switch
        ButtonGroup                   matlab.ui.container.ButtonGroup
        Button_WheelAngleTest         matlab.ui.control.RadioButton
        Button_Ackerman               matlab.ui.control.RadioButton
        Button_Ujoint                 matlab.ui.control.RadioButton
        Panel_2                       matlab.ui.container.Panel
        CheckBox                      matlab.ui.control.CheckBox
        InputMisalignmentEditField_3  matlab.ui.control.NumericEditField
        EditField_3                   matlab.ui.control.NumericEditField
        AxisMisalignment2degreeLabel  matlab.ui.control.Label
        EditField_2                   matlab.ui.control.NumericEditField
        AxisMisalignment1degreeLabel  matlab.ui.control.Label
        Lamp_D                        matlab.ui.control.Lamp
        Panel                         matlab.ui.container.Panel
        EditField                     matlab.ui.control.NumericEditField
        EditFieldLabel                matlab.ui.control.Label
        Lamp_S                        matlab.ui.control.Lamp
        UjointModeSelectionSwitch     matlab.ui.control.Switch
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        Image_Rack_Pinion             matlab.ui.control.Image
        Panel_WheelAngleTest          matlab.ui.container.Panel
        pEditField                    matlab.ui.control.NumericEditField
        pLabel                        matlab.ui.control.Label
        DEditField                    matlab.ui.control.NumericEditField
        DEditFieldLabel               matlab.ui.control.Label
        L_rodEditField                matlab.ui.control.NumericEditField
        L_rodLabel                    matlab.ui.control.Label
        L_armEditField                matlab.ui.control.NumericEditField
        L_armLabel                    matlab.ui.control.Label
        L_rackEditField               matlab.ui.control.NumericEditField
        L_rackLabel                   matlab.ui.control.Label
        WBEditField                   matlab.ui.control.NumericEditField
        WBLabel                       matlab.ui.control.Label
        TWEditField                   matlab.ui.control.NumericEditField
        TWLabel                       matlab.ui.control.Label
        Button_MechanismTest          matlab.ui.control.Button
        Panel_AckermanResult          matlab.ui.container.Panel
        Panel_3                       matlab.ui.container.Panel
        outLabel                      matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        inLabel                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        ForYXT14is079EditField        matlab.ui.control.NumericEditField
        ForYXT14is079Label            matlab.ui.control.Label
        WBForYXT14is1570mmEditField   matlab.ui.control.NumericEditField
        WBForYXT14is1570mmLabel       matlab.ui.control.Label
        TWForYXT14is1200mmEditField   matlab.ui.control.NumericEditField
        TWForYXT14is1200mmLabel       matlab.ui.control.Label
        mmEditField                   matlab.ui.control.NumericEditField
        mmEditFieldLabel              matlab.ui.control.Label
        Image_TurnningRatio           matlab.ui.control.Image
        Button_AckermanC              matlab.ui.control.Button
        Button_DrawU                  matlab.ui.control.StateButton
        Image_Ujoint                  matlab.ui.control.Image
        UIAxes_DoubleU                matlab.ui.control.UIAxes
        UIAxes_SingleU                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        output_1 % Description
    end
    
    methods (Access = private)
        function output_w = ujpint(app,input_w,gamma,theta) 
                 output_w = (cosd(gamma)/(1-(sind(gamma)^2*cosd(theta)^2)))*input_w;            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name='车辆转向设计辅助软件';
        end

        % Value changed function: Button_DrawU
        function Button_DrawUValueChanged(app, event)
%% Variables(Single)
gamma_set=app.EditField.Value;
gamma_lge=num2str(app.EditField.Value);
angular_position = linspace(0,360);
gamma = [0,gamma_set];
input_w = 1;
%% Creating Arrays(Single)
for j = 1:2
  for i = 1:100
      output1(i,j) = ujoint(input_w,gamma(j),angular_position(i));
  end
end
%% Variables(Double)
gammaD_set1=app.EditField_2.Value;
gammaD_set2=app.EditField_3.Value;
misalignment_set=app.InputMisalignmentEditField_3.Value;
gamma = [gammaD_set1,gammaD_set2];
c = 90;
misalignment = [0,30,45,60,75,90,misalignment_set];
misalignment_lge=num2str(app.InputMisalignmentEditField_3.Value);
gammaD_set1_lge=app.EditField_2.Value;
gammaD_set2_lge=app.EditField_3.Value;

%% Creating Arrays(Double)
% Arrays for various axis misalignments
for j = 1:2
  for i = 1:100
      outputD1(i,j) = ujoint(input_w,gamma(j),angular_position(i));
  end
end
% Single array for comparison
for i = 1:100
    output_origindeg(i) = outputD1(i,2); % creating single array
end
% Arrays for various input misalignments
if app.CheckBox.Value == 0
    for j = 1:6
        for i = 1:100
            outputD2(i,j) = ujoint(output_origindeg(i),gamma(1),angular_position(i)+misalignment(j));
        end
    end
else
    for j = 7:7
        for i = 1:100
            outputD3(i,j) = ujoint(output_origindeg(i),gamma(1),angular_position(i)+misalignment(j));
        end
    end
end
%% Plots
if strcmp(app.UjointModeSelectionSwitch.Value,'单万向节')
    plot(app.UIAxes_SingleU,angular_position,output1)
    lgd = legend(app.UIAxes_SingleU,'0°',gamma_lge);
    title(lgd,'空间轴夹角')
else
    plot(app.UIAxes_DoubleU,angular_position,output_origindeg,'--')    
    if app.CheckBox.Value == 0
        plot(app.UIAxes_DoubleU,angular_position,output_origindeg,'--',angular_position,outputD2)
        lgd2 = legend(app.UIAxes_DoubleU,'原始输入','0°','30°','45°','60°','75°','90°');
        title(lgd2,'万向节间相角')
    else
        plot(app.UIAxes_DoubleU,angular_position,outputD3,angular_position,output_origindeg,'--')
        lgd3 = legend(app.UIAxes_DoubleU,'单万向节传动比','双万向节传动比');
        title(lgd3,'万向节间相角')
    end
end
        end

        % Value changed function: UjointModeSelectionSwitch
        function UjointModeSelectionSwitchValueChanged(app, event)
            clear_x=linspace(0,0);
            if strcmp(app.UjointModeSelectionSwitch.Value,'单万向节')
               app.Lamp_S.Enable="on";
               app.Lamp_D.Enable="off";
               app.UIAxes_SingleU.Visible="on";
               app.UIAxes_DoubleU.Visible="off";
               app.Panel_2.Visible="off";
               app.Panel.Visible="on";
               plot(app.UIAxes_DoubleU,clear_x,sin(clear_x))
               legend(app.UIAxes_DoubleU,'off')
            else
               app.Lamp_D.Enable="on";
               app.Lamp_S.Enable="off";
               app.UIAxes_SingleU.Visible="off";
               app.UIAxes_DoubleU.Visible="on";
               app.Panel_2.Visible="on";
               app.Panel.Visible="off";
               plot(app.UIAxes_SingleU,clear_x,sin(clear_x))
               legend(app.UIAxes_SingleU,'off')
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            clear_x=linspace(0,0);
            if app.CheckBox.Value == 1
               app.InputMisalignmentEditField_3.Enable="on";
               legend(app.UIAxes_DoubleU,'off')
               plot(app.UIAxes_DoubleU,clear_x,sin(clear_x))  
            else 
                app.InputMisalignmentEditField_3.Enable="off";
                legend(app.UIAxes_DoubleU,'off')
                plot(app.UIAxes_DoubleU,clear_x,sin(clear_x))
            end

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
           if app.Button_Ackerman.Value==1
               app.UjointModeSelectionSwitch.Visible="off";
               app.Panel.Visible="off";
               app.Panel_2.Visible='off';
               app.UIAxes_DoubleU.Visible="off";
               app.UIAxes_SingleU.Visible="off";
               app.Image_Ujoint.Visible="off";
               app.Button_DrawU.Visible="off";
               app.Panel_AckermanResult.Visible="on";
               app.Image_TurnningRatio.Visible="on";
               app.Button_AckermanC.Visible="on";
               %梯形模块
               app.Button_MechanismTest.Visible="off";
               app.Panel_WheelAngleTest.Visible="off";
               app.TrapezoidModeSelectionSwitch.Visible="off";
               app.Image_Rack_Pinion.Visible="off";
              
           elseif  app.Button_Ujoint.Value==1
               app.UjointModeSelectionSwitch.Value='单万向节';
               app.UjointModeSelectionSwitch.Visible="on";
               app.Panel.Visible="on";
               app.UIAxes_SingleU.Visible="on";
               app.Image_Ujoint.Visible="on";
               app.Button_DrawU.Visible="on";
               app.Panel_AckermanResult.Visible="off";
               app.Image_TurnningRatio.Visible="off";
               app.Button_AckermanC.Visible="off";
               %梯形模块
               app.Button_MechanismTest.Visible="off";
               app.Panel_WheelAngleTest.Visible="off";
               app.Image_Rack_Pinion.Visible="off";
               app.TrapezoidModeSelectionSwitch.Visible="off";

           elseif app.Button_WheelAngleTest.Value==1
               app.UjointModeSelectionSwitch.Visible="off";
               app.Panel.Visible="off";
               app.Panel_2.Visible='off';
               app.UIAxes_DoubleU.Visible="off";
               app.UIAxes_SingleU.Visible="off";
               app.Image_Ujoint.Visible="off";
               app.Button_DrawU.Visible="off";
               app.Panel_AckermanResult.Visible="off";
               app.Image_TurnningRatio.Visible="on";
               app.Button_AckermanC.Visible="off";
               %梯形模块
               app.Button_MechanismTest.Visible="on";
               app.Panel_WheelAngleTest.Visible="on";
               app.Image_Rack_Pinion.Visible="on";
               app.TrapezoidModeSelectionSwitch.Visible="on";


           else       
           end
        end

        % Button pushed function: Button_AckermanC
        function Button_AckermanCPushed(app, event)
                        %%Parameters(Right turning)
                        WB= app.WBForYXT14is1570mmEditField.Value;
                        Rmin= app.mmEditField.Value;
                        TW = app.TWForYXT14is1200mmEditField.Value; 
                        X = app.ForYXT14is079EditField.Value;
                        WheelAngle_L=asin(WB/Rmin);                                                 %(value in asin function must be ratian)
                        WheelAngle_R=atan(1/(cot(WheelAngle_L)-(TW/WB).*X));
                        [Aout,Ain] = fromRadians("degrees",WheelAngle_L,WheelAngle_R);                    
                        app.outLabel.Text=num2str(Aout,4);
                        app.inLabel.Text=num2str(Ain,4);
        end

        % Button pushed function: Button_MechanismTest
        function Button_MechanismTestPushed(app, event)

TW=app.TWEditField.Value;
WB=app.WBEditField.Value;
L_rack=app.L_rackEditField.Value;
L_arm=app.L_armEditField.Value;
L_rod=app.L_rodEditField.Value;
D=app.DEditField.Value;
P_all=app.pEditField.Value;
%Parameters for YXRT14
% TW（主销中心距）=1056.7;
% WB（轴距）=1570;
% L_rack（转向器长度）=449;
% L_arm（节臂长度）=60;
% L_rod（横拉杆长度）=326;
% D（齿条与前轴距离）=90;
% P_all（齿条总移动行程）=82;

%前置梯形 
%inner wheel
for i=1:65           
P_half=linspace(0,P_all/2,65);                  
l1(i)=(TW-L_rack)/2-P_half(i);
l2(i)=(l1(i).^2+D^2).^0.5;
WheelAngle_in(i)=pi/2-atan(D./l1(i))-acos((L_arm.^2+l2(i).^2-L_rod.^2)./(2.*L_arm.*l2(i)));
WheelAngle_in2(i)=fromRadians("degrees",WheelAngle_in(i));
WheelAngle_def=WheelAngle_in(1);
WheelAngle_in2(i)=-fromRadians("degrees",WheelAngle_in(i)-WheelAngle_def);
end

%outter wheel
for i=1:65           
P_half=linspace(0,P_all/2,65);                  
l1(i)=(TW-L_rack)/2+P_half(i);
l2(i)=(l1(i).^2+D^2).^0.5;
WheelAngle_out(i)=pi/2-atan(D./l1(i))-acos((L_arm.^2+l2(i).^2-L_rod.^2)./(2.*L_arm.*l2(i)));
WheelAngle_outA(i)=fromRadians("degrees",WheelAngle_out(i));
%WheelAngle_def=WheelAngle_out(1);
WheelAngle_outA(i)=fromRadians("degrees",WheelAngle_out(i)-WheelAngle_def);
TheoreticalWheelAngle_in(i)=atan(1/(cot(WheelAngle_out(i)-WheelAngle_def)-(TW/WB)));
TheoreticalWheelAngle_in(i)=fromRadians("degrees",TheoreticalWheelAngle_in(i));
end

%Plots 前置梯形
if strcmp(app.TrapezoidModeSelectionSwitch.Value,'前置梯形')
    plot(WheelAngle_outA,WheelAngle_in2,'-b'),axis([0,50,0,50])
    grid on
    title('当前梯形转角关系图');    %标题
    xlabel('外轮转角(deg)'),ylabel('内轮转角(deg)');   %X轴坐标名称，Y轴坐标名称
    hold on
    plot(WheelAngle_outA,TheoreticalWheelAngle_in,'-r')  %以图表画出（外轮转角，理想内轮转角）轴线的范围
    legend('实际转角','理想阿克曼转向角')
    hold off

%Plots 后置梯形
else
    plot(WheelAngle_in2,WheelAngle_outA,'-b'),axis([0,50,0,50])
    grid on
    title('当前梯形转角关系图');    %标题
    xlabel('外轮转角(deg)'),ylabel('内轮转角(deg)');   %X轴坐标名称，Y轴坐标名称
    hold on
    plot(WheelAngle_outA,TheoreticalWheelAngle_in,'-r')  %以图表画出（外轮转角，理想内轮转角）轴线的范围
    legend('实际转角','理想阿克曼转向角')
    hold off
end

        end

        % Value changed function: TrapezoidModeSelectionSwitch
        function TrapezoidModeSelectionSwitchValueChanged(app, event)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {646, 646};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UjointModeSelectionSwitch = uiswitch(app.LeftPanel, 'slider');
            app.UjointModeSelectionSwitch.Items = {'单万向节', '双万向节'};
            app.UjointModeSelectionSwitch.ValueChangedFcn = createCallbackFcn(app, @UjointModeSelectionSwitchValueChanged, true);
            app.UjointModeSelectionSwitch.Value = '单万向节';
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = '单万向节面板';
            app.Lamp_S = uilamp(app.Panel);
            app.Lamp_S.Enable = 'off';
            app.EditFieldLabel = uilabel(app.Panel);
            app.EditFieldLabel.Text = '空间轴夹角(度)';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.Panel_2 = uipanel(app.LeftPanel);
            app.Panel_2.Title = '双万向节面板';
            app.Panel_2.Visible = 'off';
            app.Lamp_D = uilamp(app.Panel_2);
            app.Lamp_D.Enable = 'off';
            app.AxisMisalignment1degreeLabel = uilabel(app.Panel_2);
            app.AxisMisalignment1degreeLabel.Text = '空间轴夹角1(度)';
            app.EditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.AxisMisalignment2degreeLabel = uilabel(app.Panel_2);
            app.AxisMisalignment2degreeLabel.Text = '空间轴夹角2(度)';
            app.EditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.InputMisalignmentEditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.InputMisalignmentEditField_3.Enable = 'off';
            app.CheckBox = uicheckbox(app.Panel_2);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '自定义万向节间相角';
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '工具选择';
            app.Button_Ujoint = uiradiobutton(app.ButtonGroup);
            app.Button_Ujoint.Text = '万向节分析';
            app.Button_Ujoint.Value = true;
            app.Button_Ackerman = uiradiobutton(app.ButtonGroup);
            app.Button_Ackerman.Text = '阿克曼转角计算';
            app.Button_WheelAngleTest = uiradiobutton(app.ButtonGroup);
            app.Button_WheelAngleTest.Text = '梯形机构转角验证';
            app.TrapezoidModeSelectionSwitch = uiswitch(app.LeftPanel, 'slider');
            app.TrapezoidModeSelectionSwitch.Items = {'前置梯形', '后置梯形'};
            app.TrapezoidModeSelectionSwitch.ValueChangedFcn = createCallbackFcn(app, @TrapezoidModeSelectionSwitchValueChanged, true);
            app.TrapezoidModeSelectionSwitch.Visible = 'off';
            app.TrapezoidModeSelectionSwitch.Value = '前置梯形';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes_SingleU = uiaxes(app.GridLayout2);
            title(app.UIAxes_SingleU, '角速度比值-单万向节')
            xlabel(app.UIAxes_SingleU, '输入轴角位置 (°)')
            ylabel(app.UIAxes_SingleU, '输入输出轴角速度比值')
            zlabel(app.UIAxes_SingleU, 'Z')
            app.UIAxes_SingleU.XLim = [0 360];
            app.UIAxes_SingleU.Visible = 'off';
            app.UIAxes_DoubleU = uiaxes(app.GridLayout2);
            title(app.UIAxes_DoubleU, '角速度比值-双万向节')
            xlabel(app.UIAxes_DoubleU, '输入轴角位置 (°)')
            ylabel(app.UIAxes_DoubleU, '输入输出轴角速度比值')
            zlabel(app.UIAxes_DoubleU, 'Z')
            app.UIAxes_DoubleU.XLim = [0 360];
            app.UIAxes_DoubleU.Visible = 'off';
            app.Image_Ujoint = uiimage(app.GridLayout2);
            app.Image_Ujoint.ImageSource = fullfile(pathToMLAPP, 'Figure1.png');
            app.Button_DrawU = uibutton(app.GridLayout2, 'state');
            app.Button_DrawU.ValueChangedFcn = createCallbackFcn(app, @Button_DrawUValueChanged, true);
            app.Button_DrawU.Text = '绘制';
            app.Button_AckermanC = uibutton(app.GridLayout2, 'push');
            app.Button_AckermanC.ButtonPushedFcn = createCallbackFcn(app, @Button_AckermanCPushed, true);
            app.Button_AckermanC.Visible = 'off';
            app.Button_AckermanC.Text = '计算';
            app.Image_TurnningRatio = uiimage(app.GridLayout2);
            app.Image_TurnningRatio.Visible = 'off';
            app.Image_TurnningRatio.ImageSource = fullfile(pathToMLAPP, 'Ackerman.png');
            app.Panel_AckermanResult = uipanel(app.GridLayout2);
            app.Panel_AckermanResult.Title = '参数';
            app.Panel_AckermanResult.Visible = 'off';
            app.mmEditFieldLabel = uilabel(app.Panel_AckermanResult);
            app.mmEditFieldLabel.Text = '转弯半径（mm）';
            app.mmEditField = uieditfield(app.Panel_AckermanResult, 'numeric');
            app.TWForYXT14is1200mmLabel = uilabel(app.Panel_AckermanResult);
            app.TWForYXT14is1200mmLabel.Text = 'TW（轮距）（For YXT14 is 1200）(mm)';
            app.TWForYXT14is1200mmEditField = uieditfield(app.Panel_AckermanResult, 'numeric');
            app.WBForYXT14is1570mmLabel = uilabel(app.Panel_AckermanResult);
            app.WBForYXT14is1570mmLabel.Text = 'WB（轴距）（For YXT14 is 1570）(mm)';
            app.WBForYXT14is1570mmEditField = uieditfield(app.Panel_AckermanResult, 'numeric');
            app.ForYXT14is079Label = uilabel(app.Panel_AckermanResult);
            app.ForYXT14is079Label.Text = '阿克曼系数（For YXT14 is 0.79）';
            app.ForYXT14is079EditField = uieditfield(app.Panel_AckermanResult, 'numeric');
            app.Panel_3 = uipanel(app.Panel_AckermanResult);
            app.Panel_3.Title = '结果';
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = '内轮转角(度)';
            app.inLabel = uilabel(app.Panel_3);
            app.inLabel.Text = 'in';
            app.Label_2 = uilabel(app.Panel_3);
            app.Label_2.Text = '外轮转角(度)';
            app.outLabel = uilabel(app.Panel_3);
            app.outLabel.Text = 'out';
            app.Button_MechanismTest = uibutton(app.GridLayout2, 'push');
            app.Button_MechanismTest.ButtonPushedFcn = createCallbackFcn(app, @Button_MechanismTestPushed, true);
            app.Button_MechanismTest.Visible = 'off';
            app.Button_MechanismTest.Text = '测试';
            app.Panel_WheelAngleTest = uipanel(app.GridLayout2);
            app.Panel_WheelAngleTest.Title = '机构参数';
            app.Panel_WheelAngleTest.Visible = 'off';
            app.TWLabel = uilabel(app.Panel_WheelAngleTest);
            app.TWLabel.Text = 'TW(主销轮距)';
            app.TWEditField = uieditfield(app.Panel_WheelAngleTest, 'numeric');
            app.WBLabel = uilabel(app.Panel_WheelAngleTest);
            app.WBLabel.Text = 'WB(轴距)';
            app.WBEditField = uieditfield(app.Panel_WheelAngleTest, 'numeric');
            app.L_rackLabel = uilabel(app.Panel_WheelAngleTest);
            app.L_rackLabel.Text = 'L_rack(横拉杆断开点距离)';
            app.L_rackEditField = uieditfield(app.Panel_WheelAngleTest, 'numeric');
            app.L_armLabel = uilabel(app.Panel_WheelAngleTest);
            app.L_armLabel.Text = 'L_arm(节臂长度)';
            app.L_armEditField = uieditfield(app.Panel_WheelAngleTest, 'numeric');
            app.L_rodLabel = uilabel(app.Panel_WheelAngleTest);
            app.L_rodLabel.Text = 'L_rod(横拉杆长度)';
            app.L_rodEditField = uieditfield(app.Panel_WheelAngleTest, 'numeric');
            app.DEditFieldLabel = uilabel(app.Panel_WheelAngleTest);
            app.DEditFieldLabel.Text = 'D(齿条与前轴距离)';
            app.DEditField = uieditfield(app.Panel_WheelAngleTest, 'numeric');
            app.pLabel = uilabel(app.Panel_WheelAngleTest);
            app.pLabel.Text = '△p(齿条移动总行程)';
            app.pEditField = uieditfield(app.Panel_WheelAngleTest, 'numeric');
            app.Image_Rack_Pinion = uiimage(app.GridLayout2);
            app.Image_Rack_Pinion.Visible = 'off';
            app.Image_Rack_Pinion.ImageSource = fullfile(pathToMLAPP, 'block_steering_rack_pinion.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ujoint_CN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end